classdef One_Dimensional_Heat_Conduction_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DHeatConductionSimulatorUIFigure  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InputTab                     matlab.ui.container.Tab
        LayersEditField              matlab.ui.control.NumericEditField
        LayersEditFieldLabel         matlab.ui.control.Label
        StepsEditField               matlab.ui.control.NumericEditField
        StepsEditFieldLabel          matlab.ui.control.Label
        dxEditField                  matlab.ui.control.NumericEditField
        dxEditFieldLabel             matlab.ui.control.Label
        dtEditField                  matlab.ui.control.NumericEditField
        dtEditFieldLabel             matlab.ui.control.Label
        AlphaEditField               matlab.ui.control.NumericEditField
        AlphaEditFieldLabel          matlab.ui.control.Label
        T0EditField                  matlab.ui.control.EditField
        ToEditFieldLabel             matlab.ui.control.Label
        CSVLabel                     matlab.ui.control.Label
        OutputTextArea               matlab.ui.control.TextArea
        SimulationTab                matlab.ui.container.Tab
        ExportButton                 matlab.ui.control.Button
        SimulationTextArea           matlab.ui.control.TextArea
        TextAreaLabel_2              matlab.ui.control.Label
        AnimateButton                matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        PlotAxes                     matlab.ui.control.UIAxes
        DataImportTab                matlab.ui.container.Tab
        DataTextArea                 matlab.ui.control.TextArea
        UITable                      matlab.ui.control.Table
        LoadCsvButton                matlab.ui.control.Button
        SettingsTab                  matlab.ui.container.Tab
        AnimationspeedSlider         matlab.ui.control.Slider
        AnimationspeedSliderLabel    matlab.ui.control.Label
        BoundaryConditionCheckBox    matlab.ui.control.CheckBox
        InitialConditionCheckBox     matlab.ui.control.CheckBox
        SettingsTextArea             matlab.ui.control.TextArea
        SettingsTextAreaLabel        matlab.ui.control.Label
        SimulationSolverSwitch       matlab.ui.control.Switch
        SimulationSolverSwitchLabel  matlab.ui.control.Label
        PerformanceComparismTab      matlab.ui.container.Tab
        PerformanceTextArea          matlab.ui.control.TextArea
        AReportoftheComparismBetweenMatlabandCTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        T0 % Initial temperature array
        alpha % Thermal diffusivity
        dx % Spatial step
        dt % Time step
        steps % Number of time steps
        Layers % Number of layers
        T_out % Output temperature array
        slab % HeatSlab object for MATLAB solver
    end


    methods (Access = private)
       function update(app)
            % Get and validate T0 array input
            T0_input = strrep(app.T0EditField.Value, ',', ' ');
            app.T0 = str2num(T0_input); % Convert string to numeric array
            if isempty(app.T0) || ~isvector(app.T0) || ~isnumeric(app.T0) || length(app.T0) < 3
                app.OutputTextArea.Value = sprintf('Error: T0 must be a numeric vector with at least 3 elements (e.g., [20, 20, 50, 20, 20]). Got: %s', app.T0EditField.Value);
                app.SimulationTextArea.Value = sprintf('Error: T0 must be a numeric vector with at least 3 elements (e.g., [20, 20, 50, 20, 20]). Got: %s', app.T0EditField.Value);
                app.T0 = [];
                return;
            end

            % Convert T0 to double explicitly
            app.T0 = double(app.T0);

            % Get and validate other inputs
            app.alpha = app.AlphaEditField.Value;
            app.dx = app.dxEditField.Value;
            app.dt = app.dtEditField.Value;
            app.steps = app.StepsEditField.Value;
            app.Layers = app.LayersEditField.Value;

            % Validate numeric inputs
            if app.alpha <= 0 || app.dx <= 0 || app.dt <= 0 || app.steps <= 0 || app.Layers < 3
                app.OutputTextArea.Value = 'Error: alpha, dx, dt, steps, and Layers must be positive; Layers >= 3';
                return;
            end
            if length(app.T0) < app.Layers
                app.OutputTextArea.Value = sprintf('Error: T0 must have at least %d elements to match Layers', app.Layers);
                return;
            end

            % Check numerical stability for heat equation
            if app.alpha * app.dt / (app.dx * app.dx) > 0.5
                app.OutputTextArea.Value = 'Error: Unstable parameters (alpha*dt/dx^2 <= 0.5 required)';
                return;
            else
                app.OutputTextArea.Value = 'Is stable parameters (alpha*dt/dx^2 <= 0.5 required)';
            end

            % Validate settings
            if ~app.InitialConditionCheckBox.Value
                app.SettingsTextArea.Value = 'Error: Initial Condition must be checked';
                return;
            end
            if ~app.BoundaryConditionCheckBox.Value
                app.SettingsTextArea.Value = 'Error: Boundary Condition must be checked';
                return;
            end

            % If all inputs are valid, clear error messages
            app.OutputTextArea.Value = '';
            app.SettingsTextArea.Value = sprintf('Settings valid. Solver: %s ', app.SimulationSolverSwitch.Value);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %update(app)
            app.SimulationTextArea.Value = sprintf('Default Solver:  %s  . Optionally (Go to Settings Tab to change Simulation Solver)', app.SimulationSolverSwitch.Value);

            app.SettingsTextArea.Value = sprintf('Default Solver:  %s  ', app.SimulationSolverSwitch.Value);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Update app properties
            update(app);
%{
            % Exit if inputs are invalid
            if ~isempty(app.OutputTextArea.Value) || ~isempty(app.SettingsTextArea.Value)
                return;
            end
%}

            % Run simulation with timing
            try
                tic;
                if app.SimulationSolverSwitch.Value == "C++"
                    % Call heat_slab MEX function
                    app.T_out =heat_slab_mex(app.T0, app.alpha, app.dx, app.dt, app.steps);
                    time_cpp = toc;
                    time_matlab = NaN;
                else % Matlab solver
                    app.slab = HeatSlab(app.T0, app.alpha, app.dx, app.dt);
                    if app.BoundaryConditionCheckBox.Value
                        app.slab.T(1) = app.T0(1); % Fixed left boundary
                        app.slab.T(end) = app.T0(end); % Fixed right boundary
                    end
                    app.slab.step(app.steps);
                    app.T_out = app.slab.T;
                    time_matlab = toc;
                    time_cpp = NaN;
                end

                % Run the other solver for comparison
                tic;
                if app.SimulationSolverSwitch.Value == "Matlab"
                    temp_slab = HeatSlab(app.T0, app.alpha, app.dx, app.dt);
                    if app.BoundaryConditionCheckBox.Value
                        temp_slab.T(1) = app.T0(1);
                        temp_slab.T(end) = app.T0(end);
                    end
                    temp_slab.step(app.steps);
                    time_matlab = toc;
                else
                   heat_slab_mex(app.T0, app.alpha, app.dx, app.dt, app.steps);
                    time_cpp = toc;
                end

                % Display numeric results and timing
                app.SimulationTextArea.Value = sprintf('Temperature after %d steps: %s\nC++ time: %.4f s\nMATLAB time: %.4f s', ...
                    app.steps, num2str(app.T_out'), time_cpp, time_matlab);
    %{
         % Display numeric results in SimulationTextArea
                app.SimulationTextArea.Value = sprintf('Solver: %s\nTemperature after %d steps: %s', ...
                    app.SimulationSolverSwitch.Value, app.steps, num2str(app.T_out'));

                % Display timing comparison in PerformanceTextArea
                app.PerformanceTextArea.Value = sprintf('MATLAB Solver Time: %.4f s\nC++ Solver Time: %.4f s', ...
                    time_matlab, time_cpp);
                %}


                % Run both solvers for comparison
                tic;
                temp_slab = HeatSlab(app.T0, app.alpha, app.dx, app.dt);
                if app.BoundaryConditionCheckBox.Value
                    temp_slab.T(1) = app.T0(1);
                    temp_slab.T(end) = app.T0(end);
                end
                temp_slab.step(app.steps);
                time_matlab_comparison = toc;

                tic;
                temp_out =heat_slab_mex(app.T0, app.alpha, app.dx, app.dt, app.steps);
                time_cpp_comparison = toc;

                % Display numeric results in SimulationTextArea
                app.SimulationTextArea.Value = sprintf('Solver: %s\nTemperature after %d steps: %s', ...
                    app.SimulationSolverSwitch.Value, app.steps, num2str(app.T_out'));

                % Display timing comparison in PerformanceTextArea
                app.PerformanceTextArea.Value = sprintf('MATLAB Solver Time: %.4f s\nC++ Solver Time: %.4f s', ...
                    time_matlab_comparison, time_cpp_comparison);

                % Plot results
                plot(app.PlotAxes, app.T_out, 'LineWidth', 2);
                title(app.PlotAxes, sprintf('1D Heat Conduction after %d steps (Solver: %s)', app.steps, app.SimulationSolverSwitch.Value));
                xlabel(app.PlotAxes, 'Node');
                ylabel(app.PlotAxes, 'Temperature (°C)');
                grid(app.PlotAxes, 'on');
            catch err
                app.SimulationTextArea.Value = sprintf('Error: %s', err.message);
            end            
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
           % Update app properties
            update(app);
%{
            % Exit if inputs are invalid
            if ~isempty(app.OutputTextArea.Value) || ~isempty(app.SettingsTextArea.Value)
                return;
            end
%}
            % Initialize simulation
            try
                tic;
                if app.SimulationSolverSwitch.Value == "C++"
                    T_current =heat_slab_mex(app.T0, app.alpha, app.dx, app.dt, 1);
                else % Matlab solver
                    app.slab = HeatSlab(app.T0, app.alpha, app.dx, app.dt);
                    if app.BoundaryConditionCheckBox.Value
                        app.slab.T(1) = app.T0(1);
                        app.slab.T(end) = app.T0(end);
                    end
                    T_current = app.slab.T;
                end

                    cla(app.PlotAxes); % Clear previous plot                    
                    title(app.PlotAxes, sprintf('1D Heat Conduction at step (Solver: %s)', app.SimulationSolverSwitch.Value));
                    xlabel(app.PlotAxes, 'Node');
                    ylabel(app.PlotAxes, 'Temperature (°C)');
                    grid(app.PlotAxes, 'on')
                    hold(app.PlotAxes,"on");

                    % Plot current step
                    h = animatedline(app.PlotAxes,'LineWidth',2,'Color','b');
                    % Animation loop
                    for n = 1:length(T_current)
                            addpoints(h,n,T_current(n))
                            title(app.PlotAxes, sprintf('1D Heat Conduction at step %d (Solver: %s)', n, app.SimulationSolverSwitch.Value));
                            drawnow;
                            pause(app.AnimationspeedSlider.Value);
                    end

                    hold(app.PlotAxes,"off");

                % Store final result
                app.T_out = T_current;
                time_taken = toc;
                app.SimulationTextArea.Value = sprintf('Solver: %s\nAnimation complete. Final temperature: %s\nAnimation time: %.4f s', ...
                    app.SimulationSolverSwitch.Value, num2str(app.T_out'), time_taken);
            catch err
                app.SimulationTextArea.Value = sprintf('Error during animation: %s', err.message);
            end

                %{
                        % Call MEX function for one step
                    T_current =heat_slab_mex(T_current, app.alpha, app.dx, app.dt, 1);
                    else
                        % Advance one step with HeatSlab
                        app.slab.step(1);
                        if app.BoundaryConditionCheckBox.Value
                            app.slab.T(1) = app.T0(1);
                            app.slab.T(end) = app.T0(end);
                        end
                        T_current = app.slab.T;
                    end
                'XData',app.steps(1:n),'YData',
                %}
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset all input fields to default values
            app.T0EditField.Value = '[20, 40, 50, 30,20]';
            app.AlphaEditField.Value = 0.0001;
            app.dxEditField.Value = 0.01;
            app.dtEditField.Value = 0.1;
            app.StepsEditField.Value = 5;
            app.LayersEditField.Value = 5;
            app.SimulationSolverSwitch.Value = 'Matlab';
            app.InitialConditionCheckBox.Value = true;
            app.BoundaryConditionCheckBox.Value = true;
            app.OutputTextArea.Value = '';
            app.DataTextArea.Value = '';
            app.SettingsTextArea.Value = '';
            app.AnimationspeedSlider.Value = 0.1;
            app.SettingsTextArea.Value = sprintf('Default Solver:  %s', app.SimulationSolverSwitch.Value);
            app.UITable.Data = {};
            cla(app.PlotAxes); % Clear plot
        end

        % Button pushed function: LoadCsvButton
        function LoadCsvButtonPushed(app, event)
            % Prompt user to select a CSV file
            [file, path] = uigetfile('*.csv', 'Select T0 CSV File');
            if file == 0 % User canceled
                app.DataTextArea.Value = 'Error: No file selected';
                return;
            end

            % Read and validate CSV file
            try
                data = readmatrix(fullfile(path, file));
                app.T0 = data(:)'; % Ensure row vector
                if ~isvector(app.T0) || ~isnumeric(app.T0) || length(app.T0) < 3
                    app.DataTextArea.Value = 'Error: CSV must contain a single row or column of at least 3 numeric values';
                    return;
                end
                app.T0EditField.Value = num2str(app.T0);
                app.UITable.Data = num2cell(app.T0(:));
                app.DataTextArea.Value = 'T0 loaded successfully from CSV';
            catch err
                app.DataTextArea.Value = sprintf('Error loading CSV: %s', err.message);
            end
        end

        % Value changed function: LayersEditField
        function LayersEditFieldValueChanged(app, event)
            app.Layers = app.LayersEditField.Value;
            if length(app.T0) < app.Layers
                app.OutputTextArea.Value = sprintf('Error: T0 must have at least %d elements to match Layers', app.Layers);
                return;
            end
        end

        % Value changed function: StepsEditField
        function StepsEditFieldValueChanged(app, event)
            app.steps = app.StepsEditField.Value;
            if app.steps <= 0
                app.OutputTextArea.Value = 'Error: steps must be positive';
                return;
            end
        end

        % Value changed function: dxEditField
        function dxEditFieldValueChanged(app, event)
            app.dx = app.dxEditField.Value;
            if app.dx <= 0
                app.OutputTextArea.Value = 'Error: dx must be positive';
                return;
            end
        end

        % Value changed function: dtEditField
        function dtEditFieldValueChanged(app, event)
            app.dt = app.dtEditField.Value;
            if app.dt <= 0
                app.OutputTextArea.Value = 'Error: dt must be positive';
                return;
            end
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.alpha = app.AlphaEditField.Value;
            if app.alpha <= 0
                app.OutputTextArea.Value = 'Error: alpha must be positive';
                return;
            end
        end

        % Value changed function: T0EditField
        function T0EditFieldValueChanged(app, event)
            T0_input = strrep(app.T0EditField.Value, ',', ' ');
            app.T0 = str2num(T0_input);
            if isempty(app.T0) || ~isvector(app.T0) || ~isnumeric(app.T0) || length(app.T0) < 3
                app.OutputTextArea.Value = 'Error: T0 must be a numeric vector with at least 3 elements';
                return;
            end
            if length(app.T0) < app.Layers
                app.OutputTextArea.Value = sprintf('Error: T0 must have at least %d elements to match Layers', app.Layers);
                return;
            end
        end

        % Value changed function: SimulationSolverSwitch
        function SimulationSolverSwitchValueChanged(app, event)
                    app.SettingsTextArea.Value = sprintf('Solver:  %s', ...
                    app.SimulationSolverSwitch.Value);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Check if simulation has been run (T_out exists)
            if isempty(app.T_out)
                app.SimulationTextArea.Value = 'Error: Run the simulation first to export results.';
                return;
            end

            % Determine the Documents directory
            if ispc
                docsFolder = fullfile(getenv('USERPROFILE'), 'Documents');
            elseif ismac
                docsFolder = fullfile(getenv('HOME'), 'Documents');
            else % Linux or others
                docsFolder = fullfile(getenv('HOME'), 'Documents');
            end

            % Create Simulation Results folder if it doesn't exist
            resultsFolder = fullfile(docsFolder, 'Simulation Results');
            if ~exist(resultsFolder, 'dir')
                mkdir(resultsFolder);
            end

            % Generate default filename with timestamp
            timestamp = datestr(now, 'yyyy-mm-dd_HH-MM-SS');
            defaultFileName = sprintf('simulation_results_%s.txt', timestamp);

            % Open file picker with default path set to Simulation Results folder
            [file, path] = uiputfile('*.txt', 'Save Simulation Results', fullfile(resultsFolder, defaultFileName));
            if file == 0 % User canceled the dialog
                app.SimulationTextArea.Value = 'Export canceled.';
                return;
            end

            % Create full file path
            fullFileName = fullfile(path, file);

            % Open file for writing
            fid = fopen(fullFileName, 'w');
            if fid == -1
                app.SimulationTextArea.Value = 'Error: Could not open file for writing.';
                return;
            end

            % Write input parameters
            fprintf(fid, 'Heat Conduction Simulator Results\n');
            fprintf(fid, 'Generated on: %s\n\n', datestr(now));
            fprintf(fid, 'Input Parameters:\n');
            fprintf(fid, 'Initial Temperature (T0): %s\n', mat2str(app.T0));
            fprintf(fid, 'Thermal Diffusivity (alpha): %.4f\n', app.alpha);
            fprintf(fid, 'Spatial Step (dx): %.4f\n', app.dx);
            fprintf(fid, 'Time Step (dt): %.4f\n', app.dt);
            fprintf(fid, 'Number of Steps: %d\n', app.steps);
            fprintf(fid, 'Number of Layers: %d\n', app.Layers);
            fprintf(fid, 'Solver Used: %s\n\n', app.SimulationSolverSwitch.Value);

            % Write output temperature array
            fprintf(fid, 'Output Temperature Array (T_out):\n');
            fprintf(fid, '%s\n', mat2str(app.T_out));

            % Close the file
            fclose(fid);

            % Update GUI with success message
            app.SimulationTextArea.Value = sprintf('Results exported to %s', fullFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DHeatConductionSimulatorUIFigure = uifigure('Visible', 'off');
            app.DHeatConductionSimulatorUIFigure.Name = '1-D Heat Conduction Simulator';
            app.DHeatConductionSimulatorUIFigure.Icon = fullfile(pathToMLAPP, 'BUKS_logo.jpg');
            app.TabGroup = uitabgroup(app.DHeatConductionSimulatorUIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.OutputTextArea = uitextarea(app.InputTab);
            app.CSVLabel = uilabel(app.InputTab);
            app.CSVLabel.Text = {'Optionally go to DATA IMPORT to Load  a    ".CSV"'; '                               For To'};
            app.ToEditFieldLabel = uilabel(app.InputTab);
            app.ToEditFieldLabel.Text = 'To       ';
            app.T0EditField = uieditfield(app.InputTab, 'text');
            app.T0EditField.ValueChangedFcn = createCallbackFcn(app, @T0EditFieldValueChanged, true);
            app.T0EditField.Tooltip = {'Must be an array of values which takes at least 3 inputs'};
            app.T0EditField.Value = '10,20,30,40,50';
            app.AlphaEditFieldLabel = uilabel(app.InputTab);
            app.AlphaEditFieldLabel.Text = 'Alpha   ';
            app.AlphaEditField = uieditfield(app.InputTab, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Tooltip = {'must be a positive number'};
            app.AlphaEditField.Value = 0.0001;
            app.dtEditFieldLabel = uilabel(app.InputTab);
            app.dtEditFieldLabel.Text = 'dt        ';
            app.dtEditField = uieditfield(app.InputTab, 'numeric');
            app.dtEditField.ValueChangedFcn = createCallbackFcn(app, @dtEditFieldValueChanged, true);
            app.dtEditField.Tooltip = {'must be a positive number'};
            app.dtEditField.Value = 0.1;
            app.dxEditFieldLabel = uilabel(app.InputTab);
            app.dxEditFieldLabel.Text = 'dx       ';
            app.dxEditField = uieditfield(app.InputTab, 'numeric');
            app.dxEditField.ValueChangedFcn = createCallbackFcn(app, @dxEditFieldValueChanged, true);
            app.dxEditField.Tooltip = {'must be a positive number'};
            app.dxEditField.Value = 0.01;
            app.StepsEditFieldLabel = uilabel(app.InputTab);
            app.StepsEditFieldLabel.Text = 'Steps    ';
            app.StepsEditField = uieditfield(app.InputTab, 'numeric');
            app.StepsEditField.ValueChangedFcn = createCallbackFcn(app, @StepsEditFieldValueChanged, true);
            app.StepsEditField.Tooltip = {'must be a positive number and must be greater than 0'};
            app.StepsEditField.Value = 5;
            app.LayersEditFieldLabel = uilabel(app.InputTab);
            app.LayersEditFieldLabel.Text = 'Layers  ';
            app.LayersEditField = uieditfield(app.InputTab, 'numeric');
            app.LayersEditField.ValueChangedFcn = createCallbackFcn(app, @LayersEditFieldValueChanged, true);
            app.LayersEditField.Tooltip = {'Must be a positive number and must equal the number of cnaracters in To'};
            app.LayersEditField.Value = 5;
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.PlotAxes = uiaxes(app.SimulationTab);
            title(app.PlotAxes, '1D Heat Conduction')
            xlabel(app.PlotAxes, 'Node')
            ylabel(app.PlotAxes, 'Temperature (°C)')
            zlabel(app.PlotAxes, 'Z')
            app.ResetButton = uibutton(app.SimulationTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RunButton = uibutton(app.SimulationTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.AnimateButton = uibutton(app.SimulationTab, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.TextAreaLabel_2 = uilabel(app.SimulationTab);
            app.TextAreaLabel_2.Text = ' ';
            app.SimulationTextArea = uitextarea(app.SimulationTab);
            app.ExportButton = uibutton(app.SimulationTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export ';
            app.DataImportTab = uitab(app.TabGroup);
            app.DataImportTab.Title = 'Data Import';
            app.LoadCsvButton = uibutton(app.DataImportTab, 'push');
            app.LoadCsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCsvButtonPushed, true);
            app.LoadCsvButton.Text = 'Load Csv';
            app.UITable = uitable(app.DataImportTab);
            app.UITable.ColumnName = {'______________Initial Temperature (To)______________'};
            app.UITable.RowName = {};
            app.DataTextArea = uitextarea(app.DataImportTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SimulationSolverSwitchLabel = uilabel(app.SettingsTab);
            app.SimulationSolverSwitchLabel.Text = 'Simulation Solver';
            app.SimulationSolverSwitch = uiswitch(app.SettingsTab, 'slider');
            app.SimulationSolverSwitch.Items = {'Matlab', 'C++'};
            app.SimulationSolverSwitch.ValueChangedFcn = createCallbackFcn(app, @SimulationSolverSwitchValueChanged, true);
            app.SimulationSolverSwitch.Value = 'Matlab';
            app.SettingsTextAreaLabel = uilabel(app.SettingsTab);
            app.SettingsTextAreaLabel.Text = 'Settings';
            app.SettingsTextArea = uitextarea(app.SettingsTab);
            app.InitialConditionCheckBox = uicheckbox(app.SettingsTab);
            app.InitialConditionCheckBox.Text = 'Initial Condition';
            app.InitialConditionCheckBox.Value = true;
            app.BoundaryConditionCheckBox = uicheckbox(app.SettingsTab);
            app.BoundaryConditionCheckBox.Text = 'Boundary Condition';
            app.BoundaryConditionCheckBox.Value = true;
            app.AnimationspeedSliderLabel = uilabel(app.SettingsTab);
            app.AnimationspeedSliderLabel.Text = 'Animation speed';
            app.AnimationspeedSlider = uislider(app.SettingsTab);
            app.AnimationspeedSlider.Limits = [0.05 1];
            app.AnimationspeedSlider.MajorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.AnimationspeedSlider.Value = 0.1;
            app.PerformanceComparismTab = uitab(app.TabGroup);
            app.PerformanceComparismTab.Title = 'Performance Comparism';
            app.AReportoftheComparismBetweenMatlabandCTextAreaLabel = uilabel(app.PerformanceComparismTab);
            app.AReportoftheComparismBetweenMatlabandCTextAreaLabel.Text = 'A Report of the Comparism Between Matlab and C++';
            app.PerformanceTextArea = uitextarea(app.PerformanceComparismTab);
            % Show the figure after all components are created
            app.DHeatConductionSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = One_Dimensional_Heat_Conduction_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DHeatConductionSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DHeatConductionSimulatorUIFigure)
        end
    end
end